classdef plotTvsZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        ChB15                  matlab.ui.control.CheckBox
        ChB14a                 matlab.ui.control.CheckBox
        ChB141                 matlab.ui.control.CheckBox
        ChB142                 matlab.ui.control.CheckBox
        ChB143                 matlab.ui.control.CheckBox
        ChB144                 matlab.ui.control.CheckBox
        ChB145                 matlab.ui.control.CheckBox
        ChB146                 matlab.ui.control.CheckBox
        ChB147                 matlab.ui.control.CheckBox
        ChB148                 matlab.ui.control.CheckBox
        ChB149                 matlab.ui.control.CheckBox
        timeSlider             matlab.ui.control.Slider
        yearSpinnerLabel       matlab.ui.control.Label
        yearSpinner            matlab.ui.control.Spinner
        monthSpinnerLabel      matlab.ui.control.Label
        monthSpinner           matlab.ui.control.Spinner
        daySpinnerLabel        matlab.ui.control.Label
        daySpinner             matlab.ui.control.Spinner
        hourSpinnerLabel       matlab.ui.control.Label
        hourSpinner            matlab.ui.control.Spinner
        SlowTimeMachineButton  matlab.ui.control.StateButton
        FastTimeMachineButton  matlab.ui.control.StateButton
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        d % Data used for plotting
        h    % handles for plots
    end
    
    methods (Access = private)

        function app = reChB_b(app)    % hide or show profiles when the values in check boxes change
            
            flags = [app.ChB15.Value  app.ChB14a.Value...
                      app.ChB141.Value app.ChB142.Value app.ChB143.Value...
                      app.ChB144.Value app.ChB145.Value app.ChB146.Value...
                      app.ChB147.Value app.ChB148.Value app.ChB149.Value];
                  
            for is = 1:11
                                            set(app.h.Ti{is}, 'Visible', flags(is))
                                            set(app.h.fr{is}, 'Visible', flags(is))
                if ~isempty(app.h.Ts{is});  set(app.h.Ts{is}, 'Visible', flags(is)); end
            end
        end

        
        function app = reChB_t(app)     % hide profiles when the slider time goes outside of the data time vector
            for is = 1:11               % show profiles when the slider time is within 1 day from the data time vector and the Check Box in on
                
                flags = [app.ChB15.Value  app.ChB14a.Value...
                      app.ChB141.Value app.ChB142.Value app.ChB143.Value...
                      app.ChB144.Value app.ChB145.Value app.ChB146.Value...
                      app.ChB147.Value app.ChB148.Value app.ChB149.Value];
                  
                if  min(abs(app.timeSlider.Value - app.d.t{is})) > 1
                                                  set(app.h.Ti{is}, 'Visible', 0);
                                                  set(app.h.fr{is}, 'Visible', 0)
                      if ~isempty(app.h.Ts{is});  set(app.h.Ts{is}, 'Visible', 0);  end
                elseif flags(is)
                                                  set(app.h.Ti{is}, 'Visible', 1);
                                                  set(app.h.fr{is}, 'Visible', 1)
                      if ~isempty(app.h.Ts{is});  set(app.h.Ts{is}, 'Visible', 1);  end
                end

            end
        end
        
        
        function app = rePlot(app) % changes the T profiles when chosen time changes
             t = app.timeSlider.Value;
             title(app.UIAxes, datestr(t, 'yyyy mmm dd, hh'))
             set(app.UIAxes, 'XLim', [-16.7 1.3], 'YLim', [0 14]);

             for is = 1:11
                 [~, indt] = min(abs(t - app.d.t{is}));
                 
                 if ~isempty(app.h.Ts{is})
                     set(app.h.fr{is}, 'YData', app.d.F0{is}(:, indt));
                     set(app.h.Ts{is}, 'XData', app.d.Tr{is}(:, indt));  end
                     set(app.h.Ti{is}, 'XData', app.d.T {is}(:, indt));
             end
        end       
        
        function app = reSlide(app)     % changes the time slider position when spinners change
            t = datetime([app.yearSpinner.Value...
                  app.monthSpinner.Value...
                  app.daySpinner.Value...
                  app.hourSpinner.Value...
                  0 0]);
            
            % reset spinners if below or above slider limits
            tslider = datetime(datevec(app.timeSlider.Limits));
            if  t < tslider(1);  t = tslider(1);  end
            if  t > tslider(2);  t = tslider(2);  end
   
            [y , m, d] = ymd( t );
            [hr, ~, ~] = hms( t );
                       
            app.yearSpinner.Value  = y;
            app.monthSpinner.Value = m;
            app.daySpinner.Value   = d;
            app.hourSpinner.Value  = hr;
            
            app.timeSlider.Value = datenum([y m d hr 0 0]);

            end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%             app.d = load('C:\DATA\4\FirnWaterCodes\W_data_preproc.mat');
            app.d = water00_preprocess(2,0,0,0);
            
            for is = 1:11
                app.d.F0{is} = frfr(app.d.T{is}, app.d.z{is}, -0.02) + 0.9;
            end
            
            hold(app.UIAxes, 'on');
             box(app.UIAxes, 'on');
             set(app.UIAxes, 'YDir', 'reverse', 'TickDir', 'out',...
                 'XLim', [-16.7 1.3], 'YLim', [0 14], 'YGrid', 'on', 'XGrid', 'on');
             xlabel(app.UIAxes, 'Depth, m (0 - glacier surface in April 2015)')
             ylabel(app.UIAxes, 'Temperature, ^oC')
                         
            t = app.timeSlider.Value;
            title(app.UIAxes, datestr(t, 'yyyy mmm dd, hh'))
                        
            c = [ 0       0       1     ;...
                  1       0       0     ;...
                  0       1       0     ;...
                  0       0       0.1724;...
                  1       0.1034  0.7241;...
                  1       0.8276  0     ;...
                  0       0.3448  0     ;...
                  0.5172  0.5172  1     ;...
                  0.6207  0.3103  0.2759;...
                  0       1.0000  0.7586;...
                  0       0.5172  0.5862 ];                % distinguishable_colors(11);

                app.h.Ts{ 1} = plot(app.UIAxes, app.d.Tr{ 1}(:, 1   ), app.d.zr{ 1}      , '.', 'Color', c( 1,:), 'Visible', 0 );
                app.h.Ti{ 1} = plot(app.UIAxes, app.d.T { 1}(:, 1   ), app.d.z { 1}      , '-', 'Color', c( 1,:), 'Visible', 0 );
                app.h.fr{ 1} = plot(app.UIAxes, -0.02                , app.d.F0{ 1}(1)   , 'v', 'Color', c( 1,:), 'Visible', 0 );
            for is = [3:11]
                [~, indt] = min(abs(t - app.d.t{is}));
                app.h.Ts{is} = plot(app.UIAxes, app.d.Tr{is}(:, indt), app.d.zr{is} + 0.9, '.', 'Color', c(is,:) );
                app.h.Ti{is} = plot(app.UIAxes, app.d.T {is}(:, indt), app.d.z {is} + 0.9, '-', 'Color', c(is,:) );
                app.h.fr{is} = plot(app.UIAxes, -0.02                , app.d.F0{is}(indt), 'v', 'Color', c(is,:) );
            end
                [~, indt] = min(abs(t - app.d.t{ 2}));
                app.h.Ti{ 2} = plot(app.UIAxes, app.d.T { 2}(:, indt), app.d.z { 2} + 0.9, '-', 'Color', c( 2,:), 'LineWidth', 2 );
                app.h.fr{ 2} = plot(app.UIAxes, -0.02                , app.d.F0{2}(indt) , 'v', 'Color', c( 2,:) );

            legend([app.h.Ti{ 1} app.h.Ti{ 2}...
                    app.h.Ti{ 3} app.h.Ti{ 4} app.h.Ti{ 5}...
                    app.h.Ti{ 6} app.h.Ti{ 7} app.h.Ti{ 8}...
                    app.h.Ti{ 9} app.h.Ti{10} app.h.Ti{11}],...
                    {'2015  ', '2014 h-a',...
                     '2014 1', '2014 2', '2014 3',...
                     '2014 4', '2014 5', '2014 6',...
                     '2014 7', '2014 8', '2014 9'}, 'Location', "southwest")
        end

        % Value changed function: yearSpinner
        function yearSpinnerValueChanged(app, event)
            app = reSlide(app);
            app = rePlot(app);
            app = reChB_t(app);
        end

        % Value changed function: monthSpinner
        function monthSpinnerValueChanged(app, event)
            app = reSlide(app);
            app = rePlot(app);
            app = reChB_t(app);
        end

        % Value changed function: daySpinner
        function daySpinnerValueChanged(app, event)
            app = reSlide(app);
            app = rePlot(app);
            app = reChB_t(app);
        end

        % Value changed function: hourSpinner
        function hourSpinnerValueChanged(app, event)
            app = reSlide(app);
            app = rePlot(app);
            app = reChB_t(app);
        end

        % Value changed function: ChB15
        function ChB15ValueChanged(app, event)
            app = reChB_b(app);
            app = reChB_t(app);
        end

        % Value changed function: ChB14a
        function ChB14aValueChanged(app, event)
            app = reChB_b(app);
            app = reChB_t(app);
        end

        % Value changed function: ChB141
        function ChB141ValueChanged(app, event)
            app = reChB_b(app);
            app = reChB_t(app);
        end

        % Value changed function: ChB142
        function ChB142ValueChanged(app, event)
            app = reChB_b(app);
            app = reChB_t(app);
        end

        % Value changed function: ChB143
        function ChB143ValueChanged(app, event)
            app = reChB_b(app);
            app = reChB_t(app);
        end

        % Value changed function: ChB144
        function ChB144ValueChanged(app, event)
            app = reChB_b(app);
            app = reChB_t(app);
        end

        % Value changed function: ChB145
        function ChB145ValueChanged(app, event)
            app = reChB_b(app);
            app = reChB_t(app);
        end

        % Value changed function: ChB146
        function ChB146ValueChanged(app, event)
            app = reChB_b(app);
            app = reChB_t(app);
        end

        % Value changed function: ChB147
        function ChB147ValueChanged(app, event)
            app = reChB_b(app);
            app = reChB_t(app);
        end

        % Value changed function: ChB148
        function ChB148ValueChanged(app, event)
            app = reChB_b(app);
            app = reChB_t(app);
        end

        % Value changed function: ChB149
        function ChB149ValueChanged(app, event)
            app = reChB_b(app);
            app = reChB_t(app);
        end

        % Value changed function: SlowTimeMachineButton
        function SlowTimeMachineButtonValueChanged(app, event)
               
            while app.SlowTimeMachineButton.Value
                   
               t = app.timeSlider.Value + datenum([0 0 0 12 0 0]);
               if t>app.timeSlider.Limits(2); break; end
               app.timeSlider.Value = t;
               
               t = datevec(t);
               app.yearSpinner.Value  = t(1);
               app.monthSpinner.Value = t(2);
               app.daySpinner.Value   = t(3);
               app.hourSpinner.Value  = t(4);
                            
               app = rePlot(app);
               app = reChB_t(app);
               pause(0.5)
           end
            
        end

        % Value changed function: FastTimeMachineButton
        function FastTimeMachineButtonValueChanged(app, event)
            
            while app.FastTimeMachineButton.Value
                   
               t = app.timeSlider.Value + datenum([0 0 5 0 0 0]);
               if t>app.timeSlider.Limits(2); break; end
               app.timeSlider.Value = t;
               
               t = datevec(t);
               app.yearSpinner.Value  = t(1);
               app.monthSpinner.Value = t(2);
               app.daySpinner.Value   = t(3);
               app.hourSpinner.Value  = t(4);
                            
               app = rePlot(app);
               app = reChB_t(app);
               pause(0.5)
            end
            
        end

        % Value changed function: timeSlider
        function timeSliderValueChanged(app, event)
            
            t = app.timeSlider.Value;
                      
            tv = datevec(t);
            app.yearSpinner.Value  = tv(1);
            app.monthSpinner.Value = tv(2);
            app.daySpinner.Value   = tv(3);
            app.hourSpinner.Value  = tv(4);
            
            app = rePlot(app);
            app = reChB_t(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ChB15 = uicheckbox(app.LeftPanel);
            app.ChB15.ValueChangedFcn = createCallbackFcn(app, @ChB15ValueChanged, true);
            app.ChB15.Text = 'S_2015';
            app.ChB14a = uicheckbox(app.LeftPanel);
            app.ChB14a.ValueChangedFcn = createCallbackFcn(app, @ChB14aValueChanged, true);
            app.ChB14a.Text = 'S_2014_a';
            app.ChB14a.Value = true;
            app.ChB141 = uicheckbox(app.LeftPanel);
            app.ChB141.ValueChangedFcn = createCallbackFcn(app, @ChB141ValueChanged, true);
            app.ChB141.Text = 'S_2014_1';
            app.ChB141.Value = true;
            app.ChB142 = uicheckbox(app.LeftPanel);
            app.ChB142.ValueChangedFcn = createCallbackFcn(app, @ChB142ValueChanged, true);
            app.ChB142.Text = 'S_2014_2';
            app.ChB142.Value = true;
            app.ChB143 = uicheckbox(app.LeftPanel);
            app.ChB143.ValueChangedFcn = createCallbackFcn(app, @ChB143ValueChanged, true);
            app.ChB143.Text = 'S_2014_3';
            app.ChB143.Value = true;
            app.ChB144 = uicheckbox(app.LeftPanel);
            app.ChB144.ValueChangedFcn = createCallbackFcn(app, @ChB144ValueChanged, true);
            app.ChB144.Text = 'S_2014_4';
            app.ChB144.Value = true;
            app.ChB145 = uicheckbox(app.LeftPanel);
            app.ChB145.ValueChangedFcn = createCallbackFcn(app, @ChB145ValueChanged, true);
            app.ChB145.Text = 'S_2014_5';
            app.ChB145.Value = true;
            app.ChB146 = uicheckbox(app.LeftPanel);
            app.ChB146.ValueChangedFcn = createCallbackFcn(app, @ChB146ValueChanged, true);
            app.ChB146.Text = 'S_2014_6';
            app.ChB146.Value = true;
            app.ChB147 = uicheckbox(app.LeftPanel);
            app.ChB147.ValueChangedFcn = createCallbackFcn(app, @ChB147ValueChanged, true);
            app.ChB147.Text = 'S_2014_7';
            app.ChB147.Value = true;
            app.ChB148 = uicheckbox(app.LeftPanel);
            app.ChB148.ValueChangedFcn = createCallbackFcn(app, @ChB148ValueChanged, true);
            app.ChB148.Text = 'S_2014_8';
            app.ChB148.Value = true;
            app.ChB149 = uicheckbox(app.LeftPanel);
            app.ChB149.ValueChangedFcn = createCallbackFcn(app, @ChB149ValueChanged, true);
            app.ChB149.Text = 'S_2014_9';
            app.ChB149.Value = true;
            app.timeSlider = uislider(app.LeftPanel);
            app.timeSlider.Limits = [735717 736430];
            app.timeSlider.MajorTicks = [735720 735751 735781 735812 735843 735873 735904 735934 735965 735996 736024 736055 736085 736116 736146 736177 736208 736238 736269 736299 736330 736361 736390 736421];
            app.timeSlider.MajorTickLabels = {'May 2014', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec', 'Jan 2015', 'Feb', 'Mar', 'Apr', 'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec', 'Jan 2016', 'Feb', 'Mar', 'Apr'};
            app.timeSlider.ValueChangedFcn = createCallbackFcn(app, @timeSliderValueChanged, true);
            app.timeSlider.MinorTicks = [];
            app.timeSlider.Value = 735717;
            app.yearSpinnerLabel = uilabel(app.LeftPanel);
            app.yearSpinnerLabel.Text = 'year';
            app.yearSpinner = uispinner(app.LeftPanel);
            app.yearSpinner.Limits = [2014 2016];
            app.yearSpinner.ValueChangedFcn = createCallbackFcn(app, @yearSpinnerValueChanged, true);
            app.yearSpinner.Value = 2014;
            app.monthSpinnerLabel = uilabel(app.LeftPanel);
            app.monthSpinnerLabel.Text = 'month';
            app.monthSpinner = uispinner(app.LeftPanel);
            app.monthSpinner.Limits = [0 13];
            app.monthSpinner.ValueChangedFcn = createCallbackFcn(app, @monthSpinnerValueChanged, true);
            app.monthSpinner.Value = 4;
            app.daySpinnerLabel = uilabel(app.LeftPanel);
            app.daySpinnerLabel.Text = 'day';
            app.daySpinner = uispinner(app.LeftPanel);
            app.daySpinner.Limits = [0 32];
            app.daySpinner.ValueChangedFcn = createCallbackFcn(app, @daySpinnerValueChanged, true);
            app.daySpinner.Value = 18;
            app.hourSpinnerLabel = uilabel(app.LeftPanel);
            app.hourSpinnerLabel.Text = 'hour';
            app.hourSpinner = uispinner(app.LeftPanel);
            app.hourSpinner.Limits = [-1 24];
            app.hourSpinner.ValueChangedFcn = createCallbackFcn(app, @hourSpinnerValueChanged, true);
            app.SlowTimeMachineButton = uibutton(app.LeftPanel, 'state');
            app.SlowTimeMachineButton.ValueChangedFcn = createCallbackFcn(app, @SlowTimeMachineButtonValueChanged, true);
            app.SlowTimeMachineButton.Text = 'Slow animation';
            app.FastTimeMachineButton = uibutton(app.LeftPanel, 'state');
            app.FastTimeMachineButton.ValueChangedFcn = createCallbackFcn(app, @FastTimeMachineButtonValueChanged, true);
            app.FastTimeMachineButton.Text = 'Fast animation';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotTvsZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end