classdef plotTvst < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        ZtopmLabel             matlab.ui.control.Label
        ztSpinner              matlab.ui.control.Spinner
        ztSlider               matlab.ui.control.Slider
        ColZBt                 matlab.ui.control.StateButton
        ZbottommLabel          matlab.ui.control.Label
        zbSpinner              matlab.ui.control.Spinner
        zbSlider               matlab.ui.control.Slider
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
        timeendSliderLabel     matlab.ui.control.Label
        timeEndSlider          matlab.ui.control.Slider
        timestartSliderLabel   matlab.ui.control.Label
        timeStartSlider        matlab.ui.control.Slider
        ChB1                   matlab.ui.control.CheckBox
        ChB2                   matlab.ui.control.CheckBox
        ChB3                   matlab.ui.control.CheckBox
        ChB4                   matlab.ui.control.CheckBox
        ChB5                   matlab.ui.control.CheckBox
        ChB6                   matlab.ui.control.CheckBox
        ChB7                   matlab.ui.control.CheckBox
        ChB8                   matlab.ui.control.CheckBox
        ChB9                   matlab.ui.control.CheckBox
        TstringstoshowLabel    matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        ResetAxisExtentButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        d % data
        h % handles
    end
    
    methods (Access = private)
            
        function app = re_z(app)
                  
                indv = find( app.d.loc_v == 1);                 % indexes of visible plots
                z = app.d.loc_z(indv);                          % depths  of visible plots
                indzout =  or( app.ztSpinner.Value >  z, z >  app.zbSpinner.Value );    % indexes of plots outside of the chosed depth range
                indzin  = and( app.ztSpinner.Value <= z, z <= app.zbSpinner.Value );    % indexes of plots outside of the chosed depth range
                set(app.h.T(indv(indzout)), 'Visible', 0);
                set(app.h.T(indv(indzin )), 'Visible', 1);
                set(app.UIAxes, 'YLimMode', 'auto');
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.d = water00_preprocess(2,0,0,0);
                       
            hold(app.UIAxes, 'on');
            box( app.UIAxes, 'on');
            xlabel(app.UIAxes, 'Time, yy-mmm-dd');
            ylabel(app.UIAxes, 'Temperature, ^oC');
                        
            cs= [ 0         0         1;...
                  1         0         0;...
                  0         1         0;...
                  0         0         0.1724;...
                  1         0.1034    0.7241;...
                  1         0.8276    0;...
                  0         0.3448    0;...
                  0.5172    0.5172    1;...
                  0.6207    0.3103    0.2759];                % distinguishable_colors(9);
            for is = 1:9
                mi(is) = app.d.zr{is+2}( 1 );
                ma(is) = app.d.zr{is+2}(end);
            end
            mi = min(mi)+0.9;
            ma = max(ma)+0.9;
            cz = jet( round( (ma-mi) / 0.05 + 1 ) );

            i = 0;
            for is = 1:9
            t = datevec(app.d.tr{is+2});
            t = datetime(t(:,1), t(:,2), t(:,3), t(:,4), t(:,5), t(:,6));
            for iz = 1:size(app.d.zr{is+2},1)
                i = i+1;
                app.d.loc_s( i  ) = is;                                                  % T-string number
                app.d.loc_z( i  ) =            app.d.zr{is+2}(iz) + 0.9;                 % depth
                app.d.loc_cz(i,:) = cz(round( (app.d.zr{is+2}(iz) + 0.9-mi)/0.05 )+1,:); % rgb color coding the depth
                app.d.loc_cs(i,:) = cs(is                                           ,:); % rgb color coding the string number
                app.d.loc_v( i,:) = 1;                                                   % visibility flag
                
                app.h.T(i) = plot(app.UIAxes, t, app.d.Tr{is+2}(iz,:), '.-', 'Color', cs(is,:),...
                    'DisplayName', ['String ' num2str(is) ', sensor n. ' num2str(iz) ', depth ' num2str(app.d.zr{is+2}(iz)+0.9) ' m'] );
            end
            end
            xtickformat(app.UIAxes, 'yy-MMM-dd')
            set(app.UIAxes, 'TickDir', 'out', 'YGrid', 'on',...
                 'XLim', [datetime([2014 2015], [8 4], [1 16], [0 0], [0 0], [0 0])],...
                 'YLimMode', 'auto');
            
            for is = 1:9
                app.h.Tlg(is) = plot(app.UIAxes, t(1:2)-2000, [0 0], '.-', 'Color', cs(is,:), 'DisplayName', num2str(is) );
            end
            app.h.lg = legend(app.h.Tlg(:), 'Location', 'south', 'NumColumns', 3);
            title(app.h.lg, 'Number of T-string')
%             app.h.lg.ItemTokenSize = [10; 18];

            colormap(app.UIAxes, cz)
            app.h.cb = colorbar(app.UIAxes, "westoutside", 'YDir', 'reverse', 'Units', 'pixels' );
            caxis(app.UIAxes, [mi ma])
            ylabel(app.h.cb, 'Depth of sensor, m')
            
%                 app.h.cb.Position([2 4]) = app.UIAxes.InnerPosition([2 4]);
%             app.zbSlider.Position([2 4]) = app.UIAxes.InnerPosition([2 4]);
%             app.ztSlider.Position([2 4]) = app.UIAxes.InnerPosition([2 4]);
            
%             disp('outer')
%             app.UIAxes.OuterPosition
%             
%             disp('inner')
%             app.UIAxes.InnerPosition
%             
%             disp('cb')
%             app.h.cb.Position
%             
%             disp('slider bottom')
%             app.zbSlider.Position
%             
%             disp('slider top')
%             app.ztSlider.Position
            
           
            % left bottom width height
            
            
        end

        % Value changed function: timeEndSlider
        function timeEndSliderValueChanged(app, event)
                 if  app.timeEndSlider.Value <= app.timeStartSlider.Value
                     app.timeEndSlider.Value = app.timeStartSlider.Value+1/24;
                 end
                 set(app.UIAxes, 'XLim', [datetime(datevec( app.timeStartSlider.Value )) datetime(datevec( app.timeEndSlider.Value ))]);
                 set(app.UIAxes, 'YLimMode', 'auto');
        end

        % Value changed function: timeStartSlider
        function timeStartSliderValueChanged(app, event)
                 if  app.timeStartSlider.Value >= app.timeEndSlider.Value
                     app.timeStartSlider.Value =  app.timeEndSlider.Value-1/24;
                 end
                 set(app.UIAxes, 'XLim', [datetime(datevec( app.timeStartSlider.Value )) datetime(datevec( app.timeEndSlider.Value ))]);
                 set(app.UIAxes, 'YLimMode', 'auto');
        end

        % Callback function
        function TmaxSpinnerValueChanged(app, event)
                 set(app.UIAxes, 'YLim', [                  app.TminSpinner.Value                          app.TmaxSpinner.Value ]);
                 set(app.UIAxes, 'YLimMode', 'auto');
        end

        % Callback function
        function TminSpinnerValueChanged(app, event)
                 set(app.UIAxes, 'YLim', [                  app.TminSpinner.Value                          app.TmaxSpinner.Value ]);
                 set(app.UIAxes, 'YLimMode', 'auto');
        end

        % Value changed function: ChB1
        function ChB1ValueChanged(app, event)
            set(app.h.T(app.d.loc_s == 1), 'Visible', app.ChB1.Value);
            app.d.loc_v(app.d.loc_s == 1) = app.ChB1.Value;
            app = re_z(app);
        end

        % Value changed function: ChB2
        function ChB2ValueChanged(app, event)
            set(app.h.T(app.d.loc_s == 2), 'Visible', app.ChB2.Value);
            app.d.loc_v(app.d.loc_s == 2) = app.ChB2.Value;
            app = re_z(app);
        end

        % Value changed function: ChB3
        function ChB3ValueChanged(app, event)
            set(app.h.T(app.d.loc_s == 3), 'Visible', app.ChB3.Value);
            app.d.loc_v(app.d.loc_s == 3) = app.ChB3.Value;
            app = re_z(app);
        end

        % Value changed function: ChB4
        function ChB4ValueChanged(app, event)
            set(app.h.T(app.d.loc_s == 4), 'Visible', app.ChB4.Value);
            app.d.loc_v(app.d.loc_s == 4) = app.ChB4.Value;
            app = re_z(app);
        end

        % Value changed function: ChB5
        function ChB5ValueChanged(app, event)
            set(app.h.T(app.d.loc_s == 5), 'Visible', app.ChB5.Value);
            app.d.loc_v(app.d.loc_s == 5) = app.ChB5.Value;
            app = re_z(app);
        end

        % Value changed function: ChB6
        function ChB6ValueChanged(app, event)
            set(app.h.T(app.d.loc_s == 6), 'Visible', app.ChB6.Value);
            app.d.loc_v(app.d.loc_s == 6) = app.ChB6.Value;
            app = re_z(app);
        end

        % Value changed function: ChB7
        function ChB7ValueChanged(app, event)
            set(app.h.T(app.d.loc_s == 7), 'Visible', app.ChB7.Value);
            app.d.loc_v(app.d.loc_s == 7) = app.ChB7.Value;
            app = re_z(app);
        end

        % Value changed function: ChB8
        function ChB8ValueChanged(app, event)
            set(app.h.T(app.d.loc_s == 8), 'Visible', app.ChB8.Value);
            app.d.loc_v(app.d.loc_s == 8) = app.ChB8.Value;
            app = re_z(app);
        end

        % Value changed function: ChB9
        function ChB9ValueChanged(app, event)
            set(app.h.T(app.d.loc_s == 9), 'Visible', app.ChB9.Value);
            app.d.loc_v(app.d.loc_s == 9) = app.ChB9.Value;
            app = re_z(app);
        end

        % Value changed function: ztSpinner
        function ztSpinnerValueChanged(app, event)
            app = re_z(app);
            app.ztSlider.Value = -app.ztSpinner.Value;
            set(app.UIAxes, 'YLimMode', 'auto');
        end

        % Value changed function: zbSpinner
        function zbSpinnerValueChanged(app, event)
            app = re_z(app);
            app.zbSlider.Value = -app.zbSpinner.Value;
            set(app.UIAxes, 'YLimMode', 'auto');
        end

        % Value changed function: ztSlider
        function ztSliderValueChanged(app, event)
            tmp = floor(app.ztSlider.Value) + round( (app.ztSlider.Value-floor(app.ztSlider.Value))/0.05) * 0.05;
            tmp = min([ -1.3 tmp]);
            tmp = max([-13.1 tmp]);
            app.ztSlider.Value = tmp;
            if app.ztSlider.Value < app.zbSlider.Value
               app.ztSlider.Value = app.zbSlider.Value;
            end
            app.ztSpinner.Value = -app.ztSlider.Value;
            app = re_z(app);
        end

        % Value changed function: zbSlider
        function zbSliderValueChanged(app, event)
            tmp = floor(app.zbSlider.Value) + round( (app.zbSlider.Value-floor(app.zbSlider.Value))/0.05) * 0.05;
            tmp = min([ -1.3 tmp]);
            tmp = max([-13.1 tmp]);
            app.zbSlider.Value = tmp;
            if app.zbSlider.Value > app.ztSlider.Value
               app.zbSlider.Value = app.ztSlider.Value;
            end
            app.zbSpinner.Value = -app.zbSlider.Value;
            app = re_z(app);
        end

        % Value changed function: ColZBt
        function ColZBtValueChanged(app, event)

                indv = find(app.d.loc_v);
                for i = 1:length(indv)%1:length(app.h.T)
                    i_loc = indv(i);
                    set(app.h.T(i_loc), 'Color'  , app.d.loc_cz(i_loc,:)*(app.ColZBt.Value==1) + ...
                                                   app.d.loc_cs(i_loc,:)*(app.ColZBt.Value==0));  % rgb color coding the depth
                end
                    set(app.h.lg      , 'Visible',                     0*(app.ColZBt.Value==1) + ...
                                                                       1*(app.ColZBt.Value==0));

        end

        % Button pushed function: ResetAxisExtentButton
        function ResetAxisExtentButtonPushed(app, event)
            set(app.UIAxes, 'XLim', [datetime([2014 2015], [4 4], [16 16], [0 0], [0 0], [0 0])],...
                'YLimMode', 'auto');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {582, 582};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {155, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ZtopmLabel = uilabel(app.LeftPanel);
            app.ZtopmLabel.Text = 'Z top, m';
            app.ztSpinner = uispinner(app.LeftPanel);
            app.ztSpinner.Step = 0.05;
            app.ztSpinner.Limits = [1.3 13.1];
            app.ztSpinner.ValueDisplayFormat = '%.2f';
            app.ztSpinner.ValueChangedFcn = createCallbackFcn(app, @ztSpinnerValueChanged, true);
            app.ztSpinner.Value = 1.3;
            app.ztSlider = uislider(app.LeftPanel);
            app.ztSlider.Limits = [-13.1 -1.3];
            app.ztSlider.MajorTicks = [-13 -12.5 -12 -11.5 -11 -10.5 -10 -9.5 -9 -8.5 -8 -7.5 -7 -6.5 -6 -5.5 -5 -4.5 -4 -3.5 -3 -2.5 -2 -1.5];
            app.ztSlider.MajorTickLabels = {'13', '12.5', '12', '11.5', '11', '10.5', '10', '9.5', '9', '8.5', '8', '7.5', '7', '6.5', '6', '5.5', '5', '4.5', '4', '3.5', '3', '2.5', '2', '1.5'};
            app.ztSlider.ValueChangedFcn = createCallbackFcn(app, @ztSliderValueChanged, true);
            app.ztSlider.MinorTicks = [];
            app.ztSlider.Value = -1.3;
            app.ColZBt = uibutton(app.LeftPanel, 'state');
            app.ColZBt.ValueChangedFcn = createCallbackFcn(app, @ColZBtValueChanged, true);
            app.ColZBt.Text = {'Color-code'; 'depths of'; 'sensors'};
            app.ZbottommLabel = uilabel(app.LeftPanel);
            app.ZbottommLabel.Text = 'Z bottom, m';
            app.zbSpinner = uispinner(app.LeftPanel);
            app.zbSpinner.Step = 0.05;
            app.zbSpinner.Limits = [1.3 13.1];
            app.zbSpinner.ValueDisplayFormat = '%.2f';
            app.zbSpinner.ValueChangedFcn = createCallbackFcn(app, @zbSpinnerValueChanged, true);
            app.zbSpinner.Value = 13.05;
            app.zbSlider = uislider(app.LeftPanel);
            app.zbSlider.Limits = [-13.1 -1.3];
            app.zbSlider.MajorTicks = [-13 -12.5 -12 -11.5 -11 -10.5 -10 -9.5 -9 -8.5 -8 -7.5 -7 -6.5 -6 -5.5 -5 -4.5 -4 -3.5 -3 -2.5 -2 -1.5];
            app.zbSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.zbSlider.ValueChangedFcn = createCallbackFcn(app, @zbSliderValueChanged, true);
            app.zbSlider.MinorTicks = [];
            app.zbSlider.Value = -13.1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time, yy-mm-dd')
            ylabel(app.UIAxes, 'Temperature, ^oC')
            app.timeendSliderLabel = uilabel(app.RightPanel);
            app.timeendSliderLabel.Text = 'time end';
            app.timeEndSlider = uislider(app.RightPanel);
            app.timeEndSlider.Limits = [735705 736070];
            app.timeEndSlider.MajorTicks = [735720 735751 735781 735812 735843 735873 735904 735934 735965 735996 736024 736055];
            app.timeEndSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', ''};
            app.timeEndSlider.ValueChangedFcn = createCallbackFcn(app, @timeEndSliderValueChanged, true);
            app.timeEndSlider.MinorTicks = [];
            app.timeEndSlider.Value = 736070;
            app.timestartSliderLabel = uilabel(app.RightPanel);
            app.timestartSliderLabel.Text = 'time start';
            app.timeStartSlider = uislider(app.RightPanel);
            app.timeStartSlider.Limits = [735705 736070];
            app.timeStartSlider.MajorTicks = [735720 735751 735781 735812 735843 735873 735904 735934 735965 735996 736024 736055];
            app.timeStartSlider.MajorTickLabels = {'May', 'Jun', 'Jul', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec', 'Jan', 'Feb', 'Mar', 'Apr'};
            app.timeStartSlider.ValueChangedFcn = createCallbackFcn(app, @timeStartSliderValueChanged, true);
            app.timeStartSlider.MinorTicks = [];
            app.timeStartSlider.Value = 735812;
            app.ChB1 = uicheckbox(app.RightPanel);
            app.ChB1.ValueChangedFcn = createCallbackFcn(app, @ChB1ValueChanged, true);
            app.ChB1.Text = '1';
            app.ChB1.Value = true;
            app.ChB2 = uicheckbox(app.RightPanel);
            app.ChB2.ValueChangedFcn = createCallbackFcn(app, @ChB2ValueChanged, true);
            app.ChB2.Text = '2';
            app.ChB2.Value = true;
            app.ChB3 = uicheckbox(app.RightPanel);
            app.ChB3.ValueChangedFcn = createCallbackFcn(app, @ChB3ValueChanged, true);
            app.ChB3.Text = '3';
            app.ChB3.Value = true;
            app.ChB4 = uicheckbox(app.RightPanel);
            app.ChB4.ValueChangedFcn = createCallbackFcn(app, @ChB4ValueChanged, true);
            app.ChB4.Text = '4';
            app.ChB4.Value = true;
            app.ChB5 = uicheckbox(app.RightPanel);
            app.ChB5.ValueChangedFcn = createCallbackFcn(app, @ChB5ValueChanged, true);
            app.ChB5.Text = '5';
            app.ChB5.Value = true;
            app.ChB6 = uicheckbox(app.RightPanel);
            app.ChB6.ValueChangedFcn = createCallbackFcn(app, @ChB6ValueChanged, true);
            app.ChB6.Text = '6';
            app.ChB6.Value = true;
            app.ChB7 = uicheckbox(app.RightPanel);
            app.ChB7.ValueChangedFcn = createCallbackFcn(app, @ChB7ValueChanged, true);
            app.ChB7.Text = '7';
            app.ChB7.Value = true;
            app.ChB8 = uicheckbox(app.RightPanel);
            app.ChB8.ValueChangedFcn = createCallbackFcn(app, @ChB8ValueChanged, true);
            app.ChB8.Text = '8';
            app.ChB8.Value = true;
            app.ChB9 = uicheckbox(app.RightPanel);
            app.ChB9.ValueChangedFcn = createCallbackFcn(app, @ChB9ValueChanged, true);
            app.ChB9.Text = '9';
            app.ChB9.Value = true;
            app.TstringstoshowLabel = uilabel(app.RightPanel);
            app.TstringstoshowLabel.Text = 'T-strings to show';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '2015';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = {'2014'; ''};
            app.ResetAxisExtentButton = uibutton(app.RightPanel, 'push');
            app.ResetAxisExtentButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxisExtentButtonPushed, true);
            app.ResetAxisExtentButton.Text = 'Reset Axis Extent';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotTvst

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end